classdef complexSeries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        ClickoncetostartdrawingClickagaintostopLabel  matlab.ui.control.Label
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)


    end
    
    methods (Access = private)
        
        function drawAndAnimate(app,src,event)
            closedLoop = true;
            xmin = -1;
            xmax = 2;
            ymin = -1;
            ymax = 2;
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'manual')
            color = [0.3,0.3,0.8];
            [x,y,phandle,markerHandle] = freeDraw(src,event,app.UIFigure,...
                app.UIAxes,color,closedLoop,xmin,xmax,ymin,ymax);

            N = length(x);
            t = linspace(0,1,N);

            % Resample
            t2 = linspace(0,1,N*2);
            x2 = interp1(t,x,t2)';
            y2 = interp1(t,y,t2)';

            Nmodes = 5;
            Ncycles = 2;
            showCircles = true;
            showConstantMode = false;
            axHandle = app.UIAxes;

            app.complexFourierArrows(axHandle,t2,x2,y2,Nmodes,Ncycles,showCircles,showConstantMode);
            
        end
    
        function complexFourierArrows(app,axHandle,t,x,y,Nm,Ncycles,showCircles,showConstantMode)
            % The number of modes is (2Nm+1)

            Nt = length(t);
            f = (x-y*1i)';
            [c,basis,fApprox] = app.complexFourier(Nm, Nt, t, f);
            
            colors = jet(2*Nm+1);
            fColor = [0,0.447,.741];
            fColorFade = [0.75,0.8,0.85];
        
            hold(axHandle,"on")
            plot(axHandle,[fApprox,fApprox(1)],'color',fColor)
            plot(axHandle,[f,f(1)],'-','color',fColorFade)
            fHandle = plot(axHandle,0,0,'color',lines(1));
            for j = 1:(2*Nm+1)
                modeQuivers{j} = quiver(axHandle,0,0,0,0,'off','color',colors(j,:));
                circHandles{j} = plot(axHandle,0,0,'color',colors(j,:));   
            end
            thetaCirc = linspace(0,2*pi,50);
            hold(axHandle,"off")

            
            nSeq = [(1:Nm);-(1:Nm)];
            nOrder = [0;nSeq(:)]';
            nIdx = nOrder+Nm+1;
        
            for K = 1:Ncycles
                for k = 1:Nt
                    fnStart = 0;
                    for j = 1:(2*Nm+1)
                        
                        % Retrieve fourier values
                        n = nOrder(j);
                        nkBasis = basis(nIdx(j),k);
                        cn = c(nIdx(j));
                        fn = cn*nkBasis;
                        
                        if(j~=1 || showConstantMode)
                            % Show the quiver arrows
                            modeQuivers{j}.XData = real(fnStart);
                            modeQuivers{j}.YData = imag(fnStart);
                            modeQuivers{j}.UData = real(fn);
                            modeQuivers{j}.VData = imag(fn);
                            % Update the circle (if necessary)
                            if(showCircles)
                                app.updateCircle(circHandles{j},abs(fn),[real(fnStart),imag(fnStart)],thetaCirc)
                            end
                        end
                
                        % Update the arrow starting location
                        fnStart = fnStart+fn;
                        
                    end
            
                    % Update the plot of f
                    fHandle.XData = real(fApprox(1:k));
                    fHandle.YData = imag(fApprox(1:k));
            
                    pause(0)
                end
            end
        end
        
        function updateCircle(app,pHandle,r,c,theta)
            pHandle.XData = c(1) + r*cos(theta);
            pHandle.YData = c(2) + r*sin(theta);
        end

        
        function [c,basis,f_approx] = complexFourier(app,Nmodes, Nt, t, f)
            % Compute the fourier basis and coefficients
            c = zeros(Nmodes*2+1,1);
            basis = zeros(Nmodes*2+1,Nt);
            for n = -Nmodes:Nmodes
                nIdx = n + Nmodes + 1;
                basis(nIdx,:) = exp(-n*2*pi*1i*t);
                f_approx = f.*basis(nIdx,:);
                % Integrate to find the coefficients
                c(nIdx) = (t(2)-t(1))*trapz(f_approx);
            end
            % Approximation of the fourier series
            f_approx = sum(c.*basis);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            app.UIFigure.WindowButtonDownFcn = @app.drawAndAnimate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.ClickoncetostartdrawingClickagaintostopLabel = uilabel(app.UIFigure);
            app.ClickoncetostartdrawingClickagaintostopLabel.Text = {'Click once to start drawing.'; 'Click again to stop.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = complexSeries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end